classdef MEAControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Boxes                         matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        Start                         matlab.ui.container.Panel
        Testlauf                      matlab.ui.control.Lamp
        Label_2                       matlab.ui.control.Label
        Messung                       matlab.ui.control.Lamp
        Label                         matlab.ui.control.Label
        ElektrodentestButton          matlab.ui.control.Button
        ImpulseEditField              matlab.ui.control.NumericEditField
        ImpulseEditFieldLabel         matlab.ui.control.Label
        SYSCALDropDown                matlab.ui.control.DropDown
        SYSCALDropDownLabel           matlab.ui.control.Label
        MEADropDown                   matlab.ui.control.DropDown
        MEADropDownLabel              matlab.ui.control.Label
        MessungstartenButton          matlab.ui.control.Button
        Pseudosection                 matlab.ui.container.Panel
        Messwerte                     matlab.ui.control.TextArea
        AktuelleMesswerteLabel        matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        Setup                         matlab.ui.container.Panel
        SurveyerzeugenButton          matlab.ui.control.Button
        DefekteBoxenEditField         matlab.ui.control.EditField
        DefekteBoxenEditFieldLabel    matlab.ui.control.Label
        DefekteElektrodenEditField    matlab.ui.control.EditField
        DefekteElektrodenEditFieldLabel  matlab.ui.control.Label
        OffsetEditField               matlab.ui.control.NumericEditField
        OffsetEditFieldLabel          matlab.ui.control.Label
        MaxSeparationEditField        matlab.ui.control.NumericEditField
        MaxSeparationEditFieldLabel   matlab.ui.control.Label
        MinSeparationEditField        matlab.ui.control.NumericEditField
        MinSeparationEditFieldLabel   matlab.ui.control.Label
        ElektrodenabstandEditField    matlab.ui.control.NumericEditField
        ElektrodenabstandEditFieldLabel  matlab.ui.control.Label
        AnzahlderElektrodenEditField  matlab.ui.control.NumericEditField
        AnzahlderElektrodenEditFieldLabel  matlab.ui.control.Label
        KonfigurationDropDown         matlab.ui.control.DropDown
        KonfigurationDropDownLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        s % Survey object
        filename % Filename under which results are stored
        % fileHandle
        simulate
        active_boxes % to be displayed in the boxes plot
        RS232
        RS485
    end

    methods (Access = private)

        function update_boxes(app)
            n = app.s.num_ele;
            patch(app.UIAxes, [0 n n 0]', [0 0 4 4]', 'w')
            yticks(app.UIAxes, 0.5:1:3.5)
            yticklabels(app.UIAxes, {'A', 'B', 'M', 'N'})
            xticks(app.UIAxes, 0.5:1:(n-0.5))
            xticklabels(app.UIAxes, 1:n)
            axis(app.UIAxes, 'image');
            box(app.UIAxes, 'on');
            patches_x = bsxfun(@plus, [0 1 1 0]', kron(0:(n-1), [1 1 1 1]));
            patches_y = bsxfun(@plus, [0 0 1 1]', repmat([0 1 2 3], 4, n));
            patch(app.UIAxes, patches_x, patches_y, 'w');
            patch(app.UIAxes, patches_x, patches_y, app.active_boxes);
            map = colorcet('D1');
            map = [map(1, :); [1 1 1]; map(end, :)];
            colormap(app.UIAxes, map);
            app.UIAxes.CLim = [-1 1];
        end

        function test_boxes(app)
            app.UIAxes.Visible = true;
            app.Testlauf.Visible = true;
            n = app.s.num_ele;
            state = [-1 -1 1 1];
            ABMN = {'A', 'B', 'M', 'N'};
            ok = true;
            for i = 1:n
                for k = 1:4
                    w = zeros(4, n);
                    w(k, i) = state(k);
                    app.active_boxes = reshape(w, 1, []);
                    pause(0.01);
                    update_boxes(app);
                    % TODO
                    % imfine = false;
                    % while ~imfine
                        % imfine = true;
                        if ~app.simulate
                            
                            ok = set_and_verify(app.RS485, app.s.box(i), ABMN{k}, 'on');
                            if ~ok
                                % catch error
                                % imfine = false;
                                throw_warning(sprintf('%s in Box %d überprüfen!', ABMN{k}, app.s.box(i)));
                            end
                        end
                        
                    % end
                end
            end
            app.active_boxes = reshape(zeros(4, n), 1, []);
            pause(0.01);
            update_boxes(app);

            app.Testlauf.Color = 'g';
            app.Messung.Visible = true;
            app.MessungstartenButton.Visible = true;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            if isfile('mea.config')
                R = read_config('mea.config');
                app.MEADropDown.Value = R.mea;
                app.SYSCALDropDown.Value = R.syscal;
                app.simulate = R.simulate;
                app.AnzahlderElektrodenEditField.Value = R.n;
                app.ElektrodenabstandEditField.Value = R.d;
                app.MaxSeparationEditField.Value = R.nmax;
            end

            app.UIAxes2.Visible = false;
            app.UIAxes.Visible = false;
            app.Testlauf.Visible = false;
            app.Messung.Visible = false;
            app.ElektrodentestButton.Visible = false;
            % app.simulate = true;
            app.Messwerte.Visible = false;
            if ~app.simulate
                app.MessungstartenButton.Enable = false;
                if ~isequal(app.SYSCALDropDown.Value, '---')
                    value = app.SYSCALDropDown.Value;
                    pause(0.5);
                    try
                        port = init_syscal(value, 1);
                    catch
                        uialert(app.UIFigure, ...
                            sprintf('Fehler beim Öffnen von %s', value), 'SYSCAL');
                    end
                    if exist('port', 'var')
                        app.RS232 = port;
                    end
                end
                if ~isequal(app.MEADropDown.Value, '---')
                    value = app.MEADropDown.Value;
                    pause(0.5);
                    try
                        port = init_mea(value);
                    catch
                        uialert(app.UIFigure, ...
                            sprintf('Fehler beim Öffnen von %s', value), 'MEA');
                    end
                    if exist('port', 'var')
                        app.RS485 = port;
                        app.ElektrodentestButton.Visible = true;
                    end
                end
            end

        end

        % Button pushed function: SurveyerzeugenButton
        function SurveyerzeugenButtonPushed(app, event)
            switch app.KonfigurationDropDown.Value
                case 'Wenner'
                    conf = 'wenner';
                case 'Dipol-Dipol'
                    conf = 'dipole-dipole';
                case 'Pol-Dipol vorwärts'
                    conf = 'pol-dipole-fw';
                case 'Pol-Dipol rückwärts'
                    conf = 'pol-dipole-rv';
                case 'Schlumberger'
                    conf = 'schlumberger';
            end

            num_boxes = app.AnzahlderElektrodenEditField.Value;
            num_el = num_boxes;

            broken_el = eval( ...
                ['[', app.DefekteElektrodenEditField.Value, ']']);
            broken_box = eval( ...
                ['[', app.DefekteBoxenEditField.Value, ']']);
            d = app.ElektrodenabstandEditField.Value;
            n_min = app.MinSeparationEditField.Value;
            n_max = app.MaxSeparationEditField.Value;

            offset = app.OffsetEditField.Value;

            app.s = survey(conf, ...
                num_boxes, num_el, ...
                d, ...
                n_min, n_max, ...
                offset, ...
                broken_el, broken_box);

            app.MaxSeparationEditField.Value = app.s.n_max;

            app.filename = uiputfile('*.mea');
            drawnow();
            figure(app.UIFigure);

            app.ElektrodentestButton.Visible = true;

            app.UIAxes2.Visible = true;

            patch(app.UIAxes2, [0 app.s.total_num_ele], [-1 * app.s.n_max 0], ...
                'w', 'EdgeColor', 'None');
            yticks(app.UIAxes2, -app.s.n_max + 0.5:1:-app.s.n_min + 0.5);
            y_label = fliplr(1:app.s.n_max);
            yticklabels(app.UIAxes2, y_label);

            xticks(app.UIAxes2, 0.5:1:(app.s.num_ele - 0.5))
            xticklabels(app.UIAxes2, 1:app.s.num_ele)
            axis(app.UIAxes2, 'image');
            box(app.UIAxes2, "on");


        end

        % Button pushed function: ElektrodentestButton
        function ElektrodentestButtonPushed(app, event)
            test_boxes(app);
            app.MessungstartenButton.Enable = true;
        end

        % Value changed function: MEADropDown
        function MEADropDownValueChanged(app, event)
            value = app.MEADropDown.Value;

            try
                port = init_mea(value);
            catch
                uialert(app.UIFigure, ...
                    sprintf('Fehler beim Öffnen von %s', value), 'MEA');
            end
            if exist('port', 'var')
                app.RS485 = port;
                app.ElektrodentestButton.Visible = true;
            end
        end

        % Value changed function: SYSCALDropDown
        function SYSCALDropDownValueChanged(app, event)
            value = app.SYSCALDropDown.Value;
            try
                port = init_syscal(value, 1);
            catch
                uialert(app.UIFigure, ...
                    sprintf('Fehler beim Öffnen von %s', value), 'SYSCAL');
            end
            if exist('port', 'var')
                app.RS232 = port;
            end

        end

        % Button pushed function: MessungstartenButton
        function MessungstartenButtonPushed(app, event)
            app.Messung.Color = 'r';
            app.Messwerte.Visible = true;
            app.MessungstartenButton.Enable = false;
            map = colorcet('D1');

            prog = waitbar(0, 'Messung läuft...');
            figure(app.UIFigure);
            for i = 1:app.s.num_data
                waitbar(i / app.s.num_data, prog, sprintf('%d von %d', i, app.s.num_data));

                iA = app.s.ABMN_MEA(i, 1);
                iB = app.s.ABMN_MEA(i, 2);
                iM = app.s.ABMN_MEA(i, 3);
                iN = app.s.ABMN_MEA(i, 4);

                w = zeros(4, app.s.num_ele);

                d = app.s.d;
                offset_ele = app.s.offset_ele;

                switch app.s.type
                    case 'wenner'
                        x = 0.5 * (iM + iN);
                        n = iN - iM;
                        w(1, iA) = 1;
                        w(2, iB) = 1;
                        w(3, iM) = -1;
                        w(4, iN) = -1;

                    case 'dipole-dipole'
                        x = 0.5 * (iM + iB);
                        n = iM - iB;
                        w(1, iA) = 1;
                        w(2, iB) = 1;
                        w(3, iM) = -1;
                        w(4, iN) = -1;

                    case 'pol-dipole-fw'
                        x = 0.5 * (iM + iN);
                        n = iM - iA;
                        w(1, iA) = 1;
                        w(3, iM) = -1;
                        w(4, iN) = -1;

                    case 'pol-dipole-rv'
                        x = 0.5 * (iM + iN);
                        n = iA - iM;
                        w(1, iA) = 1;
                        w(3, iM) = -1;
                        w(4, iN) = -1;

                    case 'schlumberger'
                        x = 0.5 * (iM + iN);
                        n = iM - iA;
                        w(1, iA) = 1;
                        w(2, iB) = 1;
                        w(3, iM) = -1;
                        w(4, iN) = -1;
                end

                app.active_boxes = reshape(w, 1, []);
                update_boxes(app);

                px = [x - 1 + offset_ele, x + offset_ele,...
                    x + offset_ele, x - 1 + offset_ele];
                py = [-n, -n, -n + 1, -n + 1];

                % Messung
                if ~app.simulate
                    write(app.RS485, zeros(5, 1), 'uint8'); % reset boxes
                    pause(0.1);

                    imfine = false; % be pessimistic

                    while ~imfine
                        imfine = true;
                        ok = activate_boxes(app.RS485, app.s.ABMN_MEA(i, :));
                        if ~ok
                            % catch error
                            throw_warning('Problem mit der Aktivierung der Boxen');
                            % imfine = false;
                        end
                        [ok, answer] = inject_current(app.RS232, app.ImpulseEditField.Value);

                        if ~ok
                            % catch error
                            throw_warning(sprintf('Fehlercode %s', answer));
                            % imfine = false;
                        end

                        S = read_syscal(app.RS232);

                        voltage = double(S(6));
                        current = double(S(4));

                        if voltage > 3000
                            throw_warning('Spannung > 3 V');
                            % imfine = false;
                        elseif voltage < 10
                            throw_warning('Spannung < 10 mV');
                            % imfine = false;
                        end

                        if current < 1
                            throw_warning(sprintf('Strom: %d %d', ...
                                app.s.ABMN_MEA(i, 1), app.s.ABMN_MEA(i, 2)));
                            % imfine = false;
                        end
                    end % while

                    app.s.rhoa(i) = app.s.k(i) * voltage / current;

                    app.Messwerte.Value{1} = sprintf('U     = %.2f mV', voltage);
                    app.Messwerte.Value{2} = sprintf('I     = %.2f mA', current);
                    app.Messwerte.Value{3} = sprintf('rho_s = %.2f Ohmm', app.s.rhoa(i));

                    patch(app.UIAxes2, px, py, app.s.rhoa(i));

                    app.s.valid(i) = 1;
                    app.s.save(app.filename);
                else
                    app.Messwerte.Value{1} = sprintf('DEMO');
                    app.Messwerte.Value{2} = sprintf('DEMO');
                    app.Messwerte.Value{3} = sprintf('DEMO');
                    patch(app.UIAxes2, px, py, app.s.k(i), ...
                        'EdgeColor', 'k', ...
                        'LineWidth', 0.5);

                    app.s.rhoa(i) = app.s.k(i);
                    app.s.valid(i) = 1;
                    app.s.save(app.filename);

                end % simulate

                colormap(app.UIAxes2, map);
                h = colorbar(app.UIAxes2, ...
                    'Location', 'southoutside');
                xlabel(h, '\rho_s in \Omega\cdotm');
                pause(0.02);
            end % data

            if ~app.simulate
                app.s.save(app.filename);
            end
            delete(prog);
            uialert(app.UIFigure, ...
                sprintf('Messung gespeichert unter \n%s.', app.filename), 'SYSCAL', 'Icon', 'success');
            app.Messung.Color = 'g';
            app.MessungstartenButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Setup = uipanel(app.UIFigure);
            app.KonfigurationDropDownLabel = uilabel(app.Setup);
            app.KonfigurationDropDownLabel.Text = 'Konfiguration';
            app.KonfigurationDropDown = uidropdown(app.Setup);
            app.KonfigurationDropDown.Items = {'Wenner', 'Dipol-Dipol', 'Pol-Dipol vorwärts', 'Pol-Dipol rückwärts', 'Schlumberger'};
            app.KonfigurationDropDown.Value = 'Wenner';
            app.AnzahlderElektrodenEditFieldLabel = uilabel(app.Setup);
            app.AnzahlderElektrodenEditFieldLabel.Text = 'Anzahl der Elektroden';
            app.AnzahlderElektrodenEditField = uieditfield(app.Setup, 'numeric');
            app.AnzahlderElektrodenEditField.Limits = [1 126];
            app.AnzahlderElektrodenEditField.ValueDisplayFormat = '%3d';
            app.AnzahlderElektrodenEditField.Value = 42;
            app.ElektrodenabstandEditFieldLabel = uilabel(app.Setup);
            app.ElektrodenabstandEditFieldLabel.Text = 'Elektrodenabstand';
            app.ElektrodenabstandEditField = uieditfield(app.Setup, 'numeric');
            app.ElektrodenabstandEditField.Limits = [0.5 100];
            app.ElektrodenabstandEditField.Value = 0.5;
            app.MinSeparationEditFieldLabel = uilabel(app.Setup);
            app.MinSeparationEditFieldLabel.Text = 'Min. Separation';
            app.MinSeparationEditField = uieditfield(app.Setup, 'numeric');
            app.MinSeparationEditField.Limits = [1 999];
            app.MinSeparationEditField.Value = 1;
            app.MaxSeparationEditFieldLabel = uilabel(app.Setup);
            app.MaxSeparationEditFieldLabel.Text = 'Max. Separation';
            app.MaxSeparationEditField = uieditfield(app.Setup, 'numeric');
            app.MaxSeparationEditField.Limits = [1 999];
            app.MaxSeparationEditField.Value = 99;
            app.OffsetEditFieldLabel = uilabel(app.Setup);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.Setup, 'numeric');
            app.OffsetEditField.Limits = [0 999];
            app.DefekteElektrodenEditFieldLabel = uilabel(app.Setup);
            app.DefekteElektrodenEditFieldLabel.Text = 'Defekte Elektroden';
            app.DefekteElektrodenEditField = uieditfield(app.Setup, 'text');
            app.DefekteBoxenEditFieldLabel = uilabel(app.Setup);
            app.DefekteBoxenEditFieldLabel.Text = 'Defekte Boxen';
            app.DefekteBoxenEditField = uieditfield(app.Setup, 'text');
            app.SurveyerzeugenButton = uibutton(app.Setup, 'push');
            app.SurveyerzeugenButton.ButtonPushedFcn = createCallbackFcn(app, @SurveyerzeugenButtonPushed, true);
            app.SurveyerzeugenButton.Text = 'Survey erzeugen';
            app.Pseudosection = uipanel(app.UIFigure);
            app.UIAxes2 = uiaxes(app.Pseudosection);
            title(app.UIAxes2, 'Pseudosektion')
            zlabel(app.UIAxes2, 'Z')
            app.AktuelleMesswerteLabel = uilabel(app.Pseudosection);
            app.AktuelleMesswerteLabel.Text = '';
            app.Messwerte = uitextarea(app.Pseudosection);
            app.Start = uipanel(app.UIFigure);
            app.MessungstartenButton = uibutton(app.Start, 'push');
            app.MessungstartenButton.ButtonPushedFcn = createCallbackFcn(app, @MessungstartenButtonPushed, true);
            app.MessungstartenButton.Text = 'Messung starten';
            app.MEADropDownLabel = uilabel(app.Start);
            app.MEADropDownLabel.Text = 'MEA';
            app.MEADropDown = uidropdown(app.Start);
            app.MEADropDown.Items = {'---', 'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6'};
            app.MEADropDown.ValueChangedFcn = createCallbackFcn(app, @MEADropDownValueChanged, true);
            app.MEADropDown.Value = '---';
            app.SYSCALDropDownLabel = uilabel(app.Start);
            app.SYSCALDropDownLabel.Text = 'SYSCAL';
            app.SYSCALDropDown = uidropdown(app.Start);
            app.SYSCALDropDown.Items = {'---', 'COM1', 'COM2', 'COM3', 'COM4', 'COM5'};
            app.SYSCALDropDown.ValueChangedFcn = createCallbackFcn(app, @SYSCALDropDownValueChanged, true);
            app.SYSCALDropDown.Value = '---';
            app.ImpulseEditFieldLabel = uilabel(app.Start);
            app.ImpulseEditFieldLabel.Text = 'Impulse';
            app.ImpulseEditField = uieditfield(app.Start, 'numeric');
            app.ImpulseEditField.Limits = [3 99];
            app.ImpulseEditField.ValueDisplayFormat = '%3d';
            app.ImpulseEditField.Value = 3;
            app.ElektrodentestButton = uibutton(app.Start, 'push');
            app.ElektrodentestButton.ButtonPushedFcn = createCallbackFcn(app, @ElektrodentestButtonPushed, true);
            app.ElektrodentestButton.Text = 'Elektrodentest';
            app.Label = uilabel(app.Start);
            app.Label.Text = '';
            app.Messung = uilamp(app.Start);
            app.Label_2 = uilabel(app.Start);
            app.Label_2.Text = '';
            app.Testlauf = uilamp(app.Start);
            app.Boxes = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Boxes);
            title(app.UIAxes, 'Elektroden und Boxen')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MEAControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end